classdef Synthesiser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        FileMenu    matlab.ui.container.Menu
        ImportMenu  matlab.ui.container.Menu
        ExportMenu  matlab.ui.container.Menu
        ClearMenu   matlab.ui.container.Menu
        GridLayout  matlab.ui.container.GridLayout
        UIAxes      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        sample % The sample loaded from file for the synthesiser.
        sampleRate % The sample rate of the currently loaded audio sample.
        sampleName % The name of the file being used as a sample.
    end
    
    methods (Access = private)
        
        % Method to import an audio sample
        function [y, Fs] = importAudio(app)
            [file, path] = uigetfile(["*.mp3;*.flac;*.m4a;*.mp4;*.oga;*.ogg;*.wav", "Audio Files"]);

            if path ~= 0
                fullPath = append(path, file);
                [y, Fs] = audioread(fullPath);
                
                app.sample = y;
                app.sampleRate = Fs;
                app.sampleName = file;
            end
        end
        
        % Method to export the audio resulting from the synthesiser changes
        function fullPath = exportAudio(app)
            [file, path] = uiputfile(["*.flac;*.oga;*.ogg;*.wav", "Audio files"]);

            if path ~= 0
                fullPath = append(path, file);
                audiowrite(fullPath, app.sample, app.sampleRate);
            end
        end
        
        % Method to clear the current audio sample from storage
        function clearAudio(app)
            app.sample = [];
            app.sampleRate = 0;
            app.sampleName = "None";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: ImportMenu
        function ImportMenuSelected(app, event)
            app.importAudio();
        end

        % Menu selected function: ExportMenu
        function ExportMenuSelected(app, event)
            app.exportAudio();
        end

        % Menu selected function: ClearMenu
        function ClearMenuSelected(app, event)
            app.clearAudio();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.ImportMenu = uimenu(app.FileMenu);
            app.ImportMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportMenuSelected, true);
            app.ImportMenu.Text = 'Import';
            app.ExportMenu = uimenu(app.FileMenu);
            app.ExportMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportMenuSelected, true);
            app.ExportMenu.Text = 'Export';
            app.ClearMenu = uimenu(app.FileMenu);
            app.ClearMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearMenuSelected, true);
            app.ClearMenu.Text = 'Clear';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Spectrogram')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Synthesiser

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end